classdef APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        EstopButton                matlab.ui.control.StateButton
        CollisionButton            matlab.ui.control.Button
        LightCurtainsButton        matlab.ui.control.Button
        OnOfLamp                   matlab.ui.control.Lamp
        LampLabel                  matlab.ui.control.Label
        RestartButton              matlab.ui.control.Button
        ResumeButton               matlab.ui.control.Button
        StopButton                 matlab.ui.control.Button
        StartButton                matlab.ui.control.Button
        StateoutputEditField       matlab.ui.control.EditField
        StateoutputEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        % isRunning = false;  % Control flag for the loop
        % stopLoop = false;   % Flag to completely stop the loop
        % currentIteration = 1; % Track the current position in the loop
        % totalIterations = 100; % Set a total number of iterations

         motionHandler1  % UR3 Motion Handler
         motionHandler2  % Panda Motion Handler

         eStop
    end
    
    methods (Access = private)
        
    function runSimulation(app)
            app.isRunning = true;
        
            % Resume the loop from the current position
            % for i = app.currentIteration:app.totalIterations
            %     if app.isRunning == false || app.stopLoop == true
            %         app.currentIteration = i; % Save the current state
            %         disp(['Pausing at iteration: ', num2str(i)]);
            %         break;  % Exit loop to pause or stop
            %     end
        
                % Simulation logic (replace with your actual code)
                % disp(['Simulation running... Iteration: ', num2str(i)]);
        
                % pause(1);  % Simulate a delay
        
            %     % Check if stop condition is met
            %     if app.stopLoop
            %         disp('Stopping simulation.');
            %         break;
            %     end
            % end
            % 
            % % If simulation completes
            % if app.currentIteration >= app.totalIterations
            %     disp('Simulation completed.');
            %     app.isRunning = false;  % Reset running status
            %     app.currentIteration = 1;  % Reset to initial state
            % end

            % [app.motionHandler1, app.motionHandler2] = initializeMotionHandlers();
            % app.motionHandler1 = motionhandlersTest()

            while true
                 % app.motionHandler1.runRMRC(transl(-0.3, 0.2, 0.7), transl(-0.4, 0.3, 0.7), 5,0.05);
                 disp(app.motionHandler1.running)
                 app.motionHandler1.runRMRC(transl(-0.3, 0.3, 0.7), 5,0.05);
                 disp(app.motionHandler1.running)
                 disp(app.motionHandler1.getCurrentEndTr);
                 pause(0.5);
                 % app.motionHandler1.runRMRC(transl(-0.4, 0.3, 0.7), transl(-0.3, 0.2, 0.7),  5,0.05);
                 app.motionHandler1.runRMRC(transl(0.5, 0.3, 0.7), 5,0.05);
                 disp(app.motionHandler1.running)
                 disp(app.motionHandler1.getCurrentEndTr);
                 disp(app.motionHandler1.running)
                 pause(1)
            %      app.isRunning = true;
            end


    end
        
    end
    
    methods (Access = public)
        
        function animator(app,robots,endTrArr)
             
            for j = 1:size(endTrArr)

                % Initial joint configuration
                steps = 50;
                endTr = endTrArr(j);
                % Solve for joint configurations at the start and end transformations
                % qStart = app.motionHandler1.robot.model.ikcon(startTr, q0);  % Initial pose
                qStart = app.motionHandler1.getCurrentPos();
                qEnd = app.motionHandler1.robot.model.ikcon(endTr, qStart);  % End pose, starting from qStart
    
                % Generate joint trajectory between the start and end configurations
                qMatrix = jtraj(qStart, qEnd, steps);  % Generate smooth trajectory


                % Animate the robot following the joint trajectory
                for i = 1:steps
                    q_current = qMatrix(i, :);  % Current joint configuration
                    app.motionHandler1.robot.model.animate(q_current);  % Animate the robot
    
                    % Check for collisions
                    app.motionHandler1.checkForCollisionAndStop();
                    if ~app.motionHandler1.running
                        break;  % Stop motion if a collision is detected
                    end
    
                    % Update the ellipsoid positions and draw them during animation
                    app.motionHandler1.collisionHandler.drawEllipsoids();
    
                    pause(0.05);  % Adjust the pause duration as needed for smooth animation
                end
            end
    end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % [app.motionHandler1, app.motionHandler2] = initializeMotionHandlers();
            app.motionHandler1 = motionhandlersTest();

            % app.isRunning = true;
            app.eStop = false;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)

            % if ~app.isRunning
            %     app.StateoutputEditField.Value = "Start Simulation";
            %     app.OnOfLamp.Color = [0.00, 1.00, 0.00];
            %     app.stopLoop = false;  % Reset stop flag
            %     runSimulation(app);    % Start simulation
            % 
            %      % Call a function in the external script to initialize the robot and motion
            %      % [app.motionHandler1, app.motionHandler2] = initializeMotionHandlers();  % External script
            % 
            %      % Start the robot motion
            % while app.isRunning
            %      % app.motionHandler1.runRMRC(transl(-0.3, 0.2, 0.7), transl(-0.4, 0.3, 0.7), 5,0.05);
            %      app.motionHandler1.runRMRC(transl(-0.3, 0.3, 0.7), 5,0.05);
            %      disp(app.motionHandler1.running)
            %      disp(app.motionHandler1.getCurrentEndTr);
            %      pause(0.5);
            %      % app.motionHandler1.runRMRC(transl(-0.4, 0.3, 0.7), transl(-0.3, 0.2, 0.7),  5,0.05);
            %      app.motionHandler1.runRMRC(transl(0.5, 0.3, 0.7), 5,0.05);
            %      disp(app.motionHandler1.running)
            %      disp(app.motionHandler1.getCurrentEndTr);
            %      pause(0.5)
            % %      app.isRunning = true;
            % end
            % runSimulation(app)

            while true
    

                app.animator(transl(-0.3, 0.3, 0.7))

                app.animator(robot1, endTr1)
                app.animator(robot2, endTr2)

                disp(app.motionHandler1.running)
                pause(0.5)
                app.animator(transl(0.5, 0.3, 0.7))
                disp(app.motionHandler1.running)
                pause(0.5)
            end

        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            % app.StateoutputEditField.Value = "Stop Simulation";
            % app.OnOfLamp.Color = [1.00, 0.00, 0.00];
            % disp('Pausing simulation.');
            % app.isRunning = false;  % Pause the loop

            app.motionHandler1.eStop();

            app.motionHandler1.running = false;

            % app.motionHandler2.eStop();
            app.isRunning = false;

        end

        % Button pushed function: ResumeButton
        function ResumeButtonPushed(app, event)
            app.StateoutputEditField.Value = "Resume Simulation";
            if ~app.isRunning && ~app.stopLoop
                % disp('Resuming simulation from iteration: ' + string(app.currentIteration));
                % runSimulation(app);  % Resume from where it paused
                % app.OnOfLamp.Color = [0.00, 1.00, 0.00];  % Set lamp to green (running)
                % 
                % app.motionHandler1.running = true;  % Resume the robot motion
                % app.isRunning = true;
                % % Resume the motion (you may need to call the function again or continue from a state)
                % app.motionHandler1.runIK(transl(-0.3, 0.2, 0.7), transl(-0.4, 0.3, 0.7), 50);

                % app.motionHandler1.resume;
                app.motionHandler1.running = true;

                app.isRunning = true;
            end
        end

        % Button pushed function: RestartButton
        function RestartButtonPushed(app, event)
             % Stop the current simulation
             app.StateoutputEditField.Value = "Restarting Simulation";
             disp('Stopping simulation and resetting state.');
             
             app.isRunning = false;  % Stop the simulation
             app.stopLoop = true;    % Ensure the loop stops completely
             
             pause(0.1);  % Small delay to ensure the simulation halts (optional)
             
             % Reset the iteration and restart the simulation
             app.currentIteration = 1;  % Reset to the initial state
             app.stopLoop = false;      % Clear stop flag
             app.OnOfLamp.Color = [0.00, 1.00, 0.00];  % Set the lamp to green
             
             % Start the simulation again
             runSimulation(app);
        end

        % Value changed function: EstopButton
        function EstopButtonValueChanged(app, event)
            value = app.EstopButton.Value;
            % disp(~value)
            app.motionHandler1.running = ~value;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StateoutputEditFieldLabel = uilabel(app.UIFigure);
            app.StateoutputEditFieldLabel.Text = 'State output';
            app.StateoutputEditField = uieditfield(app.UIFigure, 'text');
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.ResumeButton = uibutton(app.UIFigure, 'push');
            app.ResumeButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeButtonPushed, true);
            app.ResumeButton.Text = 'Resume';
            app.RestartButton = uibutton(app.UIFigure, 'push');
            app.RestartButton.ButtonPushedFcn = createCallbackFcn(app, @RestartButtonPushed, true);
            app.RestartButton.Text = 'Restart';
            app.LampLabel = uilabel(app.UIFigure);
            app.LampLabel.Text = 'Lamp';
            app.OnOfLamp = uilamp(app.UIFigure);
            app.LightCurtainsButton = uibutton(app.UIFigure, 'push');
            app.LightCurtainsButton.Text = 'Light Curtains';
            app.CollisionButton = uibutton(app.UIFigure, 'push');
            app.CollisionButton.Text = 'Collision ';
            app.EstopButton = uibutton(app.UIFigure, 'state');
            app.EstopButton.ValueChangedFcn = createCallbackFcn(app, @EstopButtonValueChanged, true);
            app.EstopButton.Text = 'Estop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end