classdef APP_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ToppingsLabel               matlab.ui.control.Label
        BrownButton                 matlab.ui.control.Button
        GreenButton                 matlab.ui.control.Button
        RedButton                   matlab.ui.control.Button
        TabGroup                    matlab.ui.container.TabGroup
        UR3Tab                      matlab.ui.container.Tab
        UR3CartesianControlLabel    matlab.ui.control.Label
        YEditField                  matlab.ui.control.NumericEditField
        YEditFieldLabel             matlab.ui.control.Label
        ZEditField                  matlab.ui.control.NumericEditField
        ZEditFieldLabel             matlab.ui.control.Label
        XEditField                  matlab.ui.control.NumericEditField
        XEditFieldLabel             matlab.ui.control.Label
        Link6Slider                 matlab.ui.control.Slider
        Link6Slider_2Label          matlab.ui.control.Label
        Link5Slider                 matlab.ui.control.Slider
        Link5SliderLabel            matlab.ui.control.Label
        Link4Slider                 matlab.ui.control.Slider
        Link4SliderLabel            matlab.ui.control.Label
        Link3Slider                 matlab.ui.control.Slider
        Link3SliderLabel            matlab.ui.control.Label
        Link2Slider                 matlab.ui.control.Slider
        Link2SliderLabel            matlab.ui.control.Label
        Link1Slider                 matlab.ui.control.Slider
        Link1Label                  matlab.ui.control.Label
        PandaTab                    matlab.ui.container.Tab
        PandaCartesianControlLabel  matlab.ui.control.Label
        ZEditField_2                matlab.ui.control.NumericEditField
        ZEditField_2Label           matlab.ui.control.Label
        YEditField_2                matlab.ui.control.NumericEditField
        YEditField_2Label           matlab.ui.control.Label
        XEditField_2                matlab.ui.control.NumericEditField
        XEditField_2Label           matlab.ui.control.Label
        Link3Slider_2               matlab.ui.control.Slider
        Link3Slider_2Label          matlab.ui.control.Label
        Link6Slider_2               matlab.ui.control.Slider
        Link6SliderLabel            matlab.ui.control.Label
        Link5Slider_2               matlab.ui.control.Slider
        Link5Slider_2Label          matlab.ui.control.Label
        Link4Slider_2               matlab.ui.control.Slider
        Link4Slider_2Label          matlab.ui.control.Label
        Link7Slider                 matlab.ui.control.Slider
        Link7SliderLabel            matlab.ui.control.Label
        Link2Slider_2               matlab.ui.control.Slider
        Link2Slider_2Label          matlab.ui.control.Label
        Link1Slider_2               matlab.ui.control.Slider
        Link1Slider_2Label          matlab.ui.control.Label
        UR3EndEffectorTab           matlab.ui.container.Tab
        AngularZKnobU               matlab.ui.control.Knob
        AngularZKnobLabel           matlab.ui.control.Label
        AngularYKnobU               matlab.ui.control.Knob
        AngularYKnobLabel           matlab.ui.control.Label
        AngularXKnobU               matlab.ui.control.Knob
        AngularXKnobLabel           matlab.ui.control.Label
        YSliderUE                   matlab.ui.control.Slider
        YSliderLabel                matlab.ui.control.Label
        XSliderUE                   matlab.ui.control.Slider
        XSliderLabel                matlab.ui.control.Label
        ZSliderUE                   matlab.ui.control.Slider
        ZSliderLabel                matlab.ui.control.Label
        PandaEndEffectorTab         matlab.ui.container.Tab
        AngularZKnob_2P             matlab.ui.control.Knob
        AngularZKnob_2Label         matlab.ui.control.Label
        AngularXKnob_2P             matlab.ui.control.Knob
        AngularXKnob_2Label         matlab.ui.control.Label
        AngularYKnob_2P             matlab.ui.control.Knob
        AngularYKnob_2Label         matlab.ui.control.Label
        XSliderPE                   matlab.ui.control.Slider
        XSlider_2Label              matlab.ui.control.Label
        ZSliderPE                   matlab.ui.control.Slider
        ZSlider_2Label              matlab.ui.control.Label
        YSliderPE                   matlab.ui.control.Slider
        YSlider_2Label              matlab.ui.control.Label
        EstopButton                 matlab.ui.control.StateButton
        CollisionButton             matlab.ui.control.Button
        LightCurtainsButton         matlab.ui.control.Button
        OnOfLamp                    matlab.ui.control.Lamp
        LampLabel                   matlab.ui.control.Label
        RestartButton               matlab.ui.control.Button
        ResumeButton                matlab.ui.control.Button
        StopButton                  matlab.ui.control.Button
        StartButton                 matlab.ui.control.Button
        StateoutputEditField        matlab.ui.control.EditField
        StateoutputEditFieldLabel   matlab.ui.control.Label
    end

    
    properties (Access = public)
        % isRunning = false;  % Control flag for the loop
        % stopLoop = false;   % Flag to completely stop the loop
        % currentIteration = 1; % Track the current position in the loop
        % totalIterations = 100; % Set a total number of iterations
         robot;
         % motionHandler1  % UR3 Motion Handler
         % motionHandler2  % Panda Motion Handler

         eStop = false;
         isRunning = false;

         cubeRot = [pi/3, pi/4, 2*pi/7];
         cubeCent = [0, 0, 0.5];
         cubeSize = [0.5,0.5];
         cubeDens = 0.05
   
         %UR3 Setup
         ur3_joint1 = 0;
         ur3_joint2 = 0;
         ur3_joint3 = 0;
         ur3_joint4 = 0;
         ur3_joint5 = 0;
         ur3_joint6 = 0;
         ur3_velocityX = 0;
         ur3_velocityY = 0;
         ur3_velocityZ = 0;
         ur3_angularX = 0;
         ur3_angularY = 0;
         ur3_angularZ = 0;
         ur3_endposX = 0;
         ur3_endposY = 0;
         ur3_endposZ = 0;

         %Panda Setup
         panda_joint1 = 0;
         panda_joint2 = 0;
         panda_joint3 = 0;
         panda_joint4 = 0;
         panda_joint5 = 0;
         panda_joint6 = 0;
         panda_joint7 = 0;
         panda_velocityX = 0;
         panda_velocityY = 0;
         panda_velocityZ = 0;
         panda_angularX = 0;
         panda_angularY = 0;
         panda_angularZ = 0;
         panda_endposX = 0;
         panda_endposY = 0;
         panda_endposZ = 0;

         collision = false;
         lightCurtain = false;

         %Serial input - physical button
         serialUsed = false;
         serialportname = "/dev/ttyUSB0";
         serialBaud = 9600;
         serialObj

         animationState
    end
    
    methods (Access = private)

    function readSerialData(app,src,~) % Make Sure to add 'app' as first arguement and 'src' as second arguement
        raw = readline(src);
        data = str2num(raw);
        if data == 1
            app.eStop = true;
        end
        % addpoints(app.h,data(1),data(2));
    end
        
    function runSimulation(app)
            app.isRunning = true;
        
            % Resume the loop from the current position
            % for i = app.currentIteration:app.totalIterations
            %     if app.isRunning == false || app.stopLoop == true
            %         app.currentIteration = i; % Save the current state
            %         disp(['Pausing at iteration: ', num2str(i)]);
            %         break;  % Exit loop to pause or stop
            %     end
        
                % Simulation logic (replace with your actual code)
                % disp(['Simulation running... Iteration: ', num2str(i)]);
        
                % pause(1);  % Simulate a delay
        
            %     % Check if stop condition is met
            %     if app.stopLoop
            %         disp('Stopping simulation.');
            %         break;
            %     end
            % end
            % 
            % % If simulation completes
            % if app.currentIteration >= app.totalIterations
            %     disp('Simulation completed.');
            %     app.isRunning = false;  % Reset running status
            %     app.currentIteration = 1;  % Reset to initial state
            % end

            % [app.motionHandler1, app.motionHandler2] = initializeMotionHandlers();
            % app.motionHandler1 = motionhandlersTest()

            while true
                 % app.motionHandler1.runRMRC(transl(-0.3, 0.2, 0.7), transl(-0.4, 0.3, 0.7), 5,0.05);
                 disp(app.motionHandler1.running)
                 app.motionHandler1.runRMRC(transl(-0.3, 0.3, 0.7), 5,0.05);
                 disp(app.motionHandler1.running)
                 disp(app.motionHandler1.getCurrentEndTr);
                 pause(0.5);
                 % app.motionHandler1.runRMRC(transl(-0.4, 0.3, 0.7), transl(-0.3, 0.2, 0.7),  5,0.05);
                 app.motionHandler1.runRMRC(transl(0.5, 0.3, 0.7), 5,0.05);
                 disp(app.motionHandler1.running)
                 disp(app.motionHandler1.getCurrentEndTr);
                 disp(app.motionHandler1.running)
                 pause(1)
            %      app.isRunning = true;
            end


    end
        
        
    function jointSilderChange(app)
            
            % targetJointAngles = [app.ur3_joint1, app.ur3_joint2, app.ur3_joint3, ...
            %             app.ur3_joint4, app.ur3_joint5, app.ur3_joint6];
            % endTrs = app.robot.model.fkine(targetJointAngles);
            % endTrs1 = endTrs.T;
            % % disp(endTrs1(1:3,4));
            % % disp(app.XEditField)
            % 
            % %Update endPos Values
            % app.ur3_endposX= endTrs1(1,4);
            % app.ur3_endposY = endTrs1(2,4);
            % app.ur3_endposZ = endTrs1(3,4);
            % 
            % %Set Display to values
            % app.XEditField.Value = app.ur3_endposX;
            % app.YEditField.Value = app.ur3_endposY;
            % app.ZEditField.Value = app.ur3_endposZ;

            app.animationState = 0;
    end

    function endPosUr3Change(app)
            
            % targetJointAngles = [app.ur3_joint1, app.ur3_joint2, app.ur3_joint3, ...
            %             app.ur3_joint4, app.ur3_joint5, app.ur3_joint6];
            % endTrs = transl(app.ur3_endposX,app.ur3_endposY,app.ur3_endposZ)
            % 
            % % targetJointAngles = 
            % %Set Display to values
            % app.XEditField.Value = app.ur3_endposX;
            % app.YEditField.Value = app.ur3_endposY;
            % app.ZEditField.Value = app.ur3_endposZ;
            app.animationState = 1;
            % app.Link1Slider.Value
    end

    function velocityChanged(app)
            %value for statechange
            app.animationState = 2;
           
    end

    end
    
    methods (Access = public)
        
        function animator(app,robots,endTrArr)
             
            for j = 1:size(endTrArr)

                % Initial joint configuration
                steps = 50;
                endTr = endTrArr(j);
                % Solve for joint configurations at the start and end transformations
                % qStart = app.motionHandler1.robot.model.ikcon(startTr, q0);  % Initial pose
                qStart = app.motionHandler1.getCurrentPos();
                qEnd = app.motionHandler1.robot.model.ikcon(endTr, qStart);  % End pose, starting from qStart
    
                % Generate joint trajectory between the start and end configurations
                qMatrix = jtraj(qStart, qEnd, steps);  % Generate smooth trajectory


                % Animate the robot following the joint trajectory
                for i = 1:steps
                    q_current = qMatrix(i, :);  % Current joint configuration
                    app.motionHandler1.robot.model.animate(q_current);  % Animate the robot
    
                    % Check for collisions
                    app.motionHandler1.checkForCollisionAndStop();
                    if ~app.motionHandler1.running
                        break;  % Stop motion if a collision is detected
                    end
    
                    % Update the ellipsoid positions and draw them during animation
                    app.motionHandler1.collisionHandler.drawEllipsoids();
    
                    pause(0.05);  % Adjust the pause duration as needed for smooth animation
                end
            end
    end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % [app.motionHandler1, app.motionHandler2] = initializeMotionHandlers();
            %app.motionHandler1 = motionhandlersTest();
            if app.serialUsed
                app.serialObj = serialport(app.serialportname,app.serialBaud);
                configureTerminator(app.serialObj,"CR/LF");
                flush(app.serialObj);

                configureCallback(app.serialObj,"terminator",@(src, event) readSerialData(app,src,event));
            end
            pause(1)

            % app.robot = UR3e(transl(-0.5,0.2,0.5));
            app.animationState = 0;
            % app.isRunning = true;
            app.eStop = false;

            Main(app);
            %robot_and_pancake(app);
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)

            % if ~app.isRunning
            %     app.StateoutputEditField.Value = "Start Simulation";
            %     app.OnOfLamp.Color = [0.00, 1.00, 0.00];
            %     app.stopLoop = false;  % Reset stop flag
            %     runSimulation(app);    % Start simulation
            % 
            %      % Call a function in the external script to initialize the robot and motion
            %      % [app.motionHandler1, app.motionHandler2] = initializeMotionHandlers();  % External script
            % 
            %      % Start the robot motion
            % while app.isRunning
            %      % app.motionHandler1.runRMRC(transl(-0.3, 0.2, 0.7), transl(-0.4, 0.3, 0.7), 5,0.05);
            %      app.motionHandler1.runRMRC(transl(-0.3, 0.3, 0.7), 5,0.05);
            %      disp(app.motionHandler1.running)
            %      disp(app.motionHandler1.getCurrentEndTr);
            %      pause(0.5);
            %      % app.motionHandler1.runRMRC(transl(-0.4, 0.3, 0.7), transl(-0.3, 0.2, 0.7),  5,0.05);
            %      app.motionHandler1.runRMRC(transl(0.5, 0.3, 0.7), 5,0.05);
            %      disp(app.motionHandler1.running)
            %      disp(app.motionHandler1.getCurrentEndTr);
            %      pause(0.5)
            % %      app.isRunning = true;
            % end
            % runSimulation(app)

            %while true
    

            %    app.animator(transl(-0.3, 0.3, 0.7))
 
            %    app.animator(robot1, endTr1)
            %    app.animator(robot2, endTr2)

            %    disp(app.motionHandler1.running)
            %    pause(0.5)
            %    app.animator(transl(0.5, 0.3, 0.7))
            %    disp(app.motionHandler1.running)
            %    pause(0.5)
            %end

            app.isRunning = true;
            %Main(app);

        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            % app.StateoutputEditField.Value = "Stop Simulation";
            % app.OnOfLamp.Color = [1.00, 0.00, 0.00];
            % disp('Pausing simulation.');
            % app.isRunning = false;  % Pause the loop

            %app.motionHandler1.eStop();

            %app.motionHandler1.running = false;

            % app.motionHandler2.eStop();
            app.isRunning = false;

        end

        % Button pushed function: ResumeButton
        function ResumeButtonPushed(app, event)
            app.StateoutputEditField.Value = "Resume Simulation";
            %if ~app.isRunning && ~app.stopLoop
            %    % disp('Resuming simulation from iteration: ' + string(app.currentIteration));
            %    % runSimulation(app);  % Resume from where it paused
            %    % app.OnOfLamp.Color = [0.00, 1.00, 0.00];  % Set lamp to green (running)
            %    % 
            %    % app.motionHandler1.running = true;  % Resume the robot motion
            %    % app.isRunning = true;
            %    % % Resume the motion (you may need to call the function again or continue from a state)
            %    % app.motionHandler1.runIK(transl(-0.3, 0.2, 0.7), transl(-0.4, 0.3, 0.7), 50);
%
            %    % app.motionHandler1.resume;
            %    %app.motionHandler1.running = true;
%
            %    app.isRunning = true;
            %    app.eStop = false;
            %end
            app.eStop = false;
            app.collision = false;
            app.lightCurtain = false;
        end

        % Button pushed function: RestartButton
        function RestartButtonPushed(app, event)
             % Stop the current simulation
             app.StateoutputEditField.Value = "Restarting Simulation";
             disp('Stopping simulation and resetting state.');
             
             % app.isRunning = false;  % Stop the simulation
             % app.stopLoop = true;    % Ensure the loop stops completely
             
             pause(0.1);  % Small delay to ensure the simulation halts (optional)
             
             % Reset the iteration and restart the simulation
             % app.currentIteration = 1;  % Reset to the initial state
             % app.stopLoop = false;      % Clear stop flag
             app.OnOfLamp.Color = [0.00, 1.00, 0.00];  % Set the lamp to green
             
             % clear all;
             close all;

             app.isRunning = false;   
             app.startupFcn();
             % Start the simulation again
             % runSimulation(app);
        end

        % Value changed function: EstopButton
        function EstopButtonValueChanged(app, event)
            value = app.EstopButton.Value;
            % disp(~value)
            app.eStop = value;
            %app.motionHandler1.running = ~value;

        end

        % Value changed function: YEditField
        function UR3YEditFieldValueChanged(app, event)
            value = app.YEditField.Value;
            app.ur3_endposY = value;
            endPosUr3Change(app);
        end

        % Value changed function: ZEditField
        function UR3ZEditFieldValueChanged(app, event)
            value = app.ZEditField.Value;
            app.ur3_endposZ = value;
            endPosUr3Change(app);
        end

        % Value changed function: XEditField
        function UR3XEditFieldValueChanged(app, event)
            value = app.XEditField.Value;
            app.ur3_endposX = value;
            endPosUr3Change(app);
        end

        % Value changed function: Link6Slider
        function UR3Link6SliderValueChanged(app, event)
            value = app.Link6Slider.Value;
            app.ur3_joint6 = deg2rad(value);
            app.jointSilderChange();
            
        end

        % Value changed function: Link5Slider
        function UR3Link5SliderValueChanged(app, event)
            value = app.Link5Slider.Value;
             app.ur3_joint5 = deg2rad(value);
             app.jointSilderChange();
         
        end

        % Value changed function: Link4Slider
        function UR3Link4SliderValueChanged(app, event)
            value = app.Link4Slider.Value;
            app.ur3_joint4 = deg2rad(value);
            app.jointSilderChange();
            
        end

        % Value changed function: Link3Slider
        function UR3Link3SliderValueChanged(app, event)
            value = app.Link3Slider.Value;
            app.ur3_joint3 = deg2rad(value);
            app.jointSilderChange();
            
        end

        % Value changed function: Link2Slider
        function UR3Link2SliderValueChanged(app, event)
            value = app.Link2Slider.Value;
            app.ur3_joint2 = deg2rad(value);
            app.jointSilderChange();
            
        end

        % Value changed function: Link1Slider
        function UR3Link1SliderValueChanged(app, event)
            value = app.Link1Slider.Value;
            app.ur3_joint1 = deg2rad(value);
            app.jointSilderChange();
            
        end

        % Value changed function: ZEditField_2
        function PandaZEditFieldValueChanged(app, event)
            value = app.ZEditField_2.Value;
            app.panda_endposZ = value;
            app.endPosUr3Change();
        end

        % Value changed function: YEditField_2
        function PandaYEditFieldValueChanged(app, event)
            value = app.YEditField_2.Value;
            app.panda_endposY = value;
            app.endPosUr3Change();
        end

        % Value changed function: XEditField_2
        function PandaXEditFieldValueChanged(app, event)
            value = app.XEditField_2.Value;
            app.panda_endposX = value;
            app.endPosUr3Change();
        end

        % Value changed function: Link3Slider_2
        function PandaLink3SliderValueChanged(app, event)
            value = app.Link3Slider_2.Value;
            app.panda_joint3 = deg2rad(value);
            app.jointSilderChange();
        end

        % Value changed function: Link6Slider_2
        function PandaLink6Slider_2ValueChanged(app, event)
            value = app.Link6Slider_2.Value;
            app.panda_joint6 = deg2rad(value);
            app.jointSilderChange();
        end

        % Value changed function: Link5Slider_2
        function PandaLink5SliderValueChanged(app, event)
            value = app.Link5Slider_2.Value;
            app.panda_joint5 = deg2rad(value);
            app.jointSilderChange();
        end

        % Value changed function: Link4Slider_2
        function PandaLink4SliderValueChanged(app, event)
            value = app.Link4Slider_2.Value;
            app.panda_joint4 = deg2rad(value);
            app.jointSilderChange();
        end

        % Value changed function: Link7Slider
        function PandaLink7SliderValueChanged(app, event)
            value = app.Link7Slider.Value;
            app.panda_joint7 = deg2rad(value);
            app.jointSilderChange();
        end

        % Value changed function: Link2Slider_2
        function PandaLink2SliderValueChanged(app, event)
            value = app.Link2Slider_2.Value;
            app.panda_joint2 = deg2rad(value);
            app.jointSilderChange();
        end

        % Value changed function: Link1Slider_2
        function PandaLink1SliderValueChanged(app, event)
            value = app.Link1Slider_2.Value;
            app.panda_joint1 = deg2rad(value);
            app.jointSilderChange();
        end

        % Value changed function: XSliderUE
        function XSliderUEValueChanged(app, event)
            value = app.XSliderUE.Value;
            app.ur3_velocityX = value;
            app.velocityChanged()

        end

        % Value changed function: YSliderUE
        function YSliderUEValueChanged(app, event)
            value = app.YSliderUE.Value;
            app.ur3_velocityY = value;
            app.velocityChanged()
        end

        % Value changed function: ZSliderUE
        function ZSliderUEValueChanged(app, event)
            value = app.ZSliderUE.Value;
            app.ur3_velocityZ = value;
            app.velocityChanged()
        end

        % Value changed function: XSliderPE
        function XSliderPEValueChanged(app, event)
            value = app.XSliderPE.Value;
            app.panda_velocityX = value;
            app.velocityChanged()
        end

        % Value changed function: ZSliderPE
        function ZSliderPEValueChanged(app, event)
            value = app.ZSliderPE.Value;
            app.panda_velocityZ = value;
            app.velocityChanged()
        end

        % Value changed function: YSliderPE
        function YSliderPEValueChanged(app, event)
            value = app.YSliderPE.Value;
            app.panda_velocityY = value;
            app.velocityChanged()
        end

        % Value changed function: AngularZKnobU
        function AngularZKnobUValueChanged(app, event)
            value = app.AngularZKnobU.Value;
            app.ur3_angularZ = value;
            
        end

        % Value changed function: AngularYKnobU
        function AngularYKnobUValueChanged(app, event)
            value = app.AngularYKnobU.Value;
            app.ur3_angularY = value;
        end

        % Value changed function: AngularXKnobU
        function AngularXKnobUValueChanged(app, event)
            value = app.AngularXKnobU.Value;
            app.ur3_angularX = value;
        end

        % Value changed function: AngularZKnob_2P
        function AngularZKnob_2PValueChanged(app, event)
            value = app.AngularZKnob_2P.Value;
            app.panda_angularZ = value;
        end

        % Value changed function: AngularXKnob_2P
        function AngularXKnob_2PValueChanged(app, event)
            value = app.AngularXKnob_2P.Value;
            app.panda_angularX = value;
        end

        % Value changed function: AngularYKnob_2P
        function AngularYKnob_2PValueChanged(app, event)
            value = app.AngularYKnob_2P.Value;
            app.panda_angularY = value;
        end

        % Button pushed function: CollisionButton
        function CollisionButtonPushed(app, event)
            app.collision = true;

        end

        % Button pushed function: LightCurtainsButton
        function LightCurtainsButtonPushed(app, event)
            app.lightCurtain = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StateoutputEditFieldLabel = uilabel(app.UIFigure);
            app.StateoutputEditFieldLabel.Text = 'State output';
            app.StateoutputEditField = uieditfield(app.UIFigure, 'text');
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.ResumeButton = uibutton(app.UIFigure, 'push');
            app.ResumeButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeButtonPushed, true);
            app.ResumeButton.Text = 'Resume';
            app.RestartButton = uibutton(app.UIFigure, 'push');
            app.RestartButton.ButtonPushedFcn = createCallbackFcn(app, @RestartButtonPushed, true);
            app.RestartButton.Text = 'Restart';
            app.LampLabel = uilabel(app.UIFigure);
            app.LampLabel.Text = 'Lamp';
            app.OnOfLamp = uilamp(app.UIFigure);
            app.LightCurtainsButton = uibutton(app.UIFigure, 'push');
            app.LightCurtainsButton.ButtonPushedFcn = createCallbackFcn(app, @LightCurtainsButtonPushed, true);
            app.LightCurtainsButton.Text = 'Light Curtains';
            app.CollisionButton = uibutton(app.UIFigure, 'push');
            app.CollisionButton.ButtonPushedFcn = createCallbackFcn(app, @CollisionButtonPushed, true);
            app.CollisionButton.Text = 'Collision ';
            app.EstopButton = uibutton(app.UIFigure, 'state');
            app.EstopButton.ValueChangedFcn = createCallbackFcn(app, @EstopButtonValueChanged, true);
            app.EstopButton.Text = 'Estop';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.UR3Tab = uitab(app.TabGroup);
            app.UR3Tab.Title = 'UR3';
            app.Link1Label = uilabel(app.UR3Tab);
            app.Link1Label.Text = 'Link 1';
            app.Link1Slider = uislider(app.UR3Tab);
            app.Link1Slider.Limits = [-180 180];
            app.Link1Slider.ValueChangedFcn = createCallbackFcn(app, @UR3Link1SliderValueChanged, true);
            app.Link2SliderLabel = uilabel(app.UR3Tab);
            app.Link2SliderLabel.Text = 'Link 2';
            app.Link2Slider = uislider(app.UR3Tab);
            app.Link2Slider.Limits = [-180 180];
            app.Link2Slider.ValueChangedFcn = createCallbackFcn(app, @UR3Link2SliderValueChanged, true);
            app.Link3SliderLabel = uilabel(app.UR3Tab);
            app.Link3SliderLabel.Text = 'Link 3';
            app.Link3Slider = uislider(app.UR3Tab);
            app.Link3Slider.Limits = [-180 180];
            app.Link3Slider.ValueChangedFcn = createCallbackFcn(app, @UR3Link3SliderValueChanged, true);
            app.Link4SliderLabel = uilabel(app.UR3Tab);
            app.Link4SliderLabel.Text = 'Link 4';
            app.Link4Slider = uislider(app.UR3Tab);
            app.Link4Slider.Limits = [-180 180];
            app.Link4Slider.ValueChangedFcn = createCallbackFcn(app, @UR3Link4SliderValueChanged, true);
            app.Link5SliderLabel = uilabel(app.UR3Tab);
            app.Link5SliderLabel.Text = 'Link 5';
            app.Link5Slider = uislider(app.UR3Tab);
            app.Link5Slider.Limits = [-180 180];
            app.Link5Slider.ValueChangedFcn = createCallbackFcn(app, @UR3Link5SliderValueChanged, true);
            app.Link6Slider_2Label = uilabel(app.UR3Tab);
            app.Link6Slider_2Label.Text = 'Link 6';
            app.Link6Slider = uislider(app.UR3Tab);
            app.Link6Slider.Limits = [-180 180];
            app.Link6Slider.ValueChangedFcn = createCallbackFcn(app, @UR3Link6SliderValueChanged, true);
            app.XEditFieldLabel = uilabel(app.UR3Tab);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UR3Tab, 'numeric');
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @UR3XEditFieldValueChanged, true);
            app.ZEditFieldLabel = uilabel(app.UR3Tab);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.UR3Tab, 'numeric');
            app.ZEditField.ValueChangedFcn = createCallbackFcn(app, @UR3ZEditFieldValueChanged, true);
            app.YEditFieldLabel = uilabel(app.UR3Tab);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UR3Tab, 'numeric');
            app.YEditField.ValueChangedFcn = createCallbackFcn(app, @UR3YEditFieldValueChanged, true);
            app.UR3CartesianControlLabel = uilabel(app.UR3Tab);
            app.UR3CartesianControlLabel.Text = 'UR3 Cartesian Control';
            app.PandaTab = uitab(app.TabGroup);
            app.PandaTab.Title = 'Panda';
            app.Link1Slider_2Label = uilabel(app.PandaTab);
            app.Link1Slider_2Label.Text = 'Link 1';
            app.Link1Slider_2 = uislider(app.PandaTab);
            app.Link1Slider_2.Limits = [-180 180];
            app.Link1Slider_2.MajorTicks = [-180 -120 -60 0 60 120 180];
            app.Link1Slider_2.ValueChangedFcn = createCallbackFcn(app, @PandaLink1SliderValueChanged, true);
            app.Link2Slider_2Label = uilabel(app.PandaTab);
            app.Link2Slider_2Label.Text = 'Link 2';
            app.Link2Slider_2 = uislider(app.PandaTab);
            app.Link2Slider_2.Limits = [-180 180];
            app.Link2Slider_2.MajorTicks = [-180 -120 -60 0 60 120 180];
            app.Link2Slider_2.ValueChangedFcn = createCallbackFcn(app, @PandaLink2SliderValueChanged, true);
            app.Link7SliderLabel = uilabel(app.PandaTab);
            app.Link7SliderLabel.Text = 'Link 7';
            app.Link7Slider = uislider(app.PandaTab);
            app.Link7Slider.Limits = [-180 180];
            app.Link7Slider.MajorTicks = [-180 -120 -60 0 60 120 180];
            app.Link7Slider.ValueChangedFcn = createCallbackFcn(app, @PandaLink7SliderValueChanged, true);
            app.Link4Slider_2Label = uilabel(app.PandaTab);
            app.Link4Slider_2Label.Text = 'Link 4';
            app.Link4Slider_2 = uislider(app.PandaTab);
            app.Link4Slider_2.Limits = [-180 180];
            app.Link4Slider_2.MajorTicks = [-180 -120 -60 0 60 120 180];
            app.Link4Slider_2.ValueChangedFcn = createCallbackFcn(app, @PandaLink4SliderValueChanged, true);
            app.Link5Slider_2Label = uilabel(app.PandaTab);
            app.Link5Slider_2Label.Text = 'Link 5';
            app.Link5Slider_2 = uislider(app.PandaTab);
            app.Link5Slider_2.Limits = [-180 180];
            app.Link5Slider_2.MajorTicks = [-180 -120 -60 0 60 120 180];
            app.Link5Slider_2.ValueChangedFcn = createCallbackFcn(app, @PandaLink5SliderValueChanged, true);
            app.Link6SliderLabel = uilabel(app.PandaTab);
            app.Link6SliderLabel.Text = 'Link 6';
            app.Link6Slider_2 = uislider(app.PandaTab);
            app.Link6Slider_2.Limits = [-180 180];
            app.Link6Slider_2.MajorTicks = [-180 -120 -60 0 60 120 180];
            app.Link6Slider_2.ValueChangedFcn = createCallbackFcn(app, @PandaLink6Slider_2ValueChanged, true);
            app.Link3Slider_2Label = uilabel(app.PandaTab);
            app.Link3Slider_2Label.Text = 'Link 3';
            app.Link3Slider_2 = uislider(app.PandaTab);
            app.Link3Slider_2.Limits = [-180 180];
            app.Link3Slider_2.MajorTicks = [-180 -120 -60 0 60 120 180];
            app.Link3Slider_2.ValueChangedFcn = createCallbackFcn(app, @PandaLink3SliderValueChanged, true);
            app.XEditField_2Label = uilabel(app.PandaTab);
            app.XEditField_2Label.Text = 'X';
            app.XEditField_2 = uieditfield(app.PandaTab, 'numeric');
            app.XEditField_2.ValueChangedFcn = createCallbackFcn(app, @PandaXEditFieldValueChanged, true);
            app.YEditField_2Label = uilabel(app.PandaTab);
            app.YEditField_2Label.Text = 'Y';
            app.YEditField_2 = uieditfield(app.PandaTab, 'numeric');
            app.YEditField_2.ValueChangedFcn = createCallbackFcn(app, @PandaYEditFieldValueChanged, true);
            app.ZEditField_2Label = uilabel(app.PandaTab);
            app.ZEditField_2Label.Text = 'Z';
            app.ZEditField_2 = uieditfield(app.PandaTab, 'numeric');
            app.ZEditField_2.ValueChangedFcn = createCallbackFcn(app, @PandaZEditFieldValueChanged, true);
            app.PandaCartesianControlLabel = uilabel(app.PandaTab);
            app.PandaCartesianControlLabel.Text = 'Panda Cartesian Control';
            app.UR3EndEffectorTab = uitab(app.TabGroup);
            app.UR3EndEffectorTab.Title = 'UR3 EndEffector';
            app.ZSliderLabel = uilabel(app.UR3EndEffectorTab);
            app.ZSliderLabel.Text = 'Z';
            app.ZSliderUE = uislider(app.UR3EndEffectorTab);
            app.ZSliderUE.Limits = [-1 1];
            app.ZSliderUE.MajorTicks = [-1 -0.6 -0.2 0 0.2 0.6 1];
            app.ZSliderUE.ValueChangedFcn = createCallbackFcn(app, @ZSliderUEValueChanged, true);
            app.XSliderLabel = uilabel(app.UR3EndEffectorTab);
            app.XSliderLabel.Text = 'X';
            app.XSliderUE = uislider(app.UR3EndEffectorTab);
            app.XSliderUE.Limits = [-1 1];
            app.XSliderUE.MajorTicks = [-1 -0.6 -0.2 0 0.2 0.6 1];
            app.XSliderUE.ValueChangedFcn = createCallbackFcn(app, @XSliderUEValueChanged, true);
            app.YSliderLabel = uilabel(app.UR3EndEffectorTab);
            app.YSliderLabel.Text = 'Y';
            app.YSliderUE = uislider(app.UR3EndEffectorTab);
            app.YSliderUE.Limits = [-1 1];
            app.YSliderUE.MajorTicks = [-1 -0.6 -0.2 0 0.2 0.6 1];
            app.YSliderUE.ValueChangedFcn = createCallbackFcn(app, @YSliderUEValueChanged, true);
            app.AngularXKnobLabel = uilabel(app.UR3EndEffectorTab);
            app.AngularXKnobLabel.Text = 'Angular X';
            app.AngularXKnobU = uiknob(app.UR3EndEffectorTab, 'continuous');
            app.AngularXKnobU.Limits = [0 1];
            app.AngularXKnobU.MajorTicks = [-1 -0.6 -0.2 0 0.2 0.6 1];
            app.AngularXKnobU.ValueChangedFcn = createCallbackFcn(app, @AngularXKnobUValueChanged, true);
            app.AngularYKnobLabel = uilabel(app.UR3EndEffectorTab);
            app.AngularYKnobLabel.Text = 'Angular Y';
            app.AngularYKnobU = uiknob(app.UR3EndEffectorTab, 'continuous');
            app.AngularYKnobU.Limits = [0 1];
            app.AngularYKnobU.MajorTicks = [-1 -0.6 -0.2 0 0.2 0.6 1];
            app.AngularYKnobU.ValueChangedFcn = createCallbackFcn(app, @AngularYKnobUValueChanged, true);
            app.AngularZKnobLabel = uilabel(app.UR3EndEffectorTab);
            app.AngularZKnobLabel.Text = 'Angular Z';
            app.AngularZKnobU = uiknob(app.UR3EndEffectorTab, 'continuous');
            app.AngularZKnobU.Limits = [0 1];
            app.AngularZKnobU.MajorTicks = [-1 -0.6 -0.2 0 0.2 0.6 1];
            app.AngularZKnobU.ValueChangedFcn = createCallbackFcn(app, @AngularZKnobUValueChanged, true);
            app.PandaEndEffectorTab = uitab(app.TabGroup);
            app.PandaEndEffectorTab.Title = 'Panda EndEffector';
            app.YSlider_2Label = uilabel(app.PandaEndEffectorTab);
            app.YSlider_2Label.Text = 'Y';
            app.YSliderPE = uislider(app.PandaEndEffectorTab);
            app.YSliderPE.Limits = [0 1];
            app.YSliderPE.ValueChangedFcn = createCallbackFcn(app, @YSliderPEValueChanged, true);
            app.ZSlider_2Label = uilabel(app.PandaEndEffectorTab);
            app.ZSlider_2Label.Text = 'Z';
            app.ZSliderPE = uislider(app.PandaEndEffectorTab);
            app.ZSliderPE.Limits = [0 1];
            app.ZSliderPE.ValueChangedFcn = createCallbackFcn(app, @ZSliderPEValueChanged, true);
            app.XSlider_2Label = uilabel(app.PandaEndEffectorTab);
            app.XSlider_2Label.Text = 'X';
            app.XSliderPE = uislider(app.PandaEndEffectorTab);
            app.XSliderPE.Limits = [0 1];
            app.XSliderPE.ValueChangedFcn = createCallbackFcn(app, @XSliderPEValueChanged, true);
            app.AngularYKnob_2Label = uilabel(app.PandaEndEffectorTab);
            app.AngularYKnob_2Label.Text = 'Angular Y';
            app.AngularYKnob_2P = uiknob(app.PandaEndEffectorTab, 'continuous');
            app.AngularYKnob_2P.ValueChangedFcn = createCallbackFcn(app, @AngularYKnob_2PValueChanged, true);
            app.AngularXKnob_2Label = uilabel(app.PandaEndEffectorTab);
            app.AngularXKnob_2Label.Text = 'Angular X';
            app.AngularXKnob_2P = uiknob(app.PandaEndEffectorTab, 'continuous');
            app.AngularXKnob_2P.ValueChangedFcn = createCallbackFcn(app, @AngularXKnob_2PValueChanged, true);
            app.AngularZKnob_2Label = uilabel(app.PandaEndEffectorTab);
            app.AngularZKnob_2Label.Text = 'Angular Z';
            app.AngularZKnob_2P = uiknob(app.PandaEndEffectorTab, 'continuous');
            app.AngularZKnob_2P.ValueChangedFcn = createCallbackFcn(app, @AngularZKnob_2PValueChanged, true);
            app.RedButton = uibutton(app.UIFigure, 'push');
            app.RedButton.Text = 'Red';
            app.GreenButton = uibutton(app.UIFigure, 'push');
            app.GreenButton.Text = 'Green';
            app.BrownButton = uibutton(app.UIFigure, 'push');
            app.BrownButton.Text = 'Brown';
            app.ToppingsLabel = uilabel(app.UIFigure);
            app.ToppingsLabel.Text = 'Toppings';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APP_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end